classdef MenuDetectaVortices < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        InvertCheckBox        matlab.ui.control.CheckBox
        MinAreaSlider         matlab.ui.control.Slider
        MinAreaSliderLabel    matlab.ui.control.Label
        ThresholdSlider       matlab.ui.control.Slider
        ThresholdSliderLabel  matlab.ui.control.Label
        DetectButton          matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: MinAreaSlider
        function MinAreaSliderValueChanging(app, event)
            changingValue = event.Value;
            fig = findobj('Name', 'Image');
            
            centroids = detectCentros(fig.UserData.Map, changingValue, app.ThresholdSlider.Value,...
                                        app.InvertCheckBox.Value);
            fig.UserData.centrosc = centroids; 
        end

        % Value changing function: ThresholdSlider
        function ThresholdSliderValueChanging(app, event)
            changingValue = event.Value;
            fig = findobj('Name', 'Image');
            
            centroids = detectCentros(fig.UserData.Map, app.MinAreaSlider.Value, changingValue,...
                                        app.InvertCheckBox.Value);
            
            fig.UserData.centrosc = centroids;
        end

        % Value changed function: InvertCheckBox
        function InvertCheckBoxValueChanged(app, event)
            value = app.InvertCheckBox.Value;
            fig = findobj('Name', 'Image');
            
            centroids = detectCentros(fig.UserData.Map, app.MinAreaSlider.Value, app.ThresholdSlider.Value,...
                                        value);
            fig.UserData.centrosc = centroids;                        
            
            
        end

        % Button pushed function: DetectButton
        function DetectButtonPushed(app, event)
            fig = findobj('Name', 'Image');
            centroids = detectCentros(fig.UserData.Map, app.MinAreaSlider.Value, app.ThresholdSlider.Value,...
                                        app.InvertCheckBox.Value);
           fig.UserData.centrosc = centroids;
           fig.UserData.trian = delaunay(centroids);
            close(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DetectButton = uibutton(app.UIFigure, 'push');
            app.DetectButton.ButtonPushedFcn = createCallbackFcn(app, @DetectButtonPushed, true);
            app.DetectButton.Text = 'Detect';
            app.ThresholdSliderLabel = uilabel(app.UIFigure);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.ThresholdSlider = uislider(app.UIFigure);
            app.ThresholdSlider.Limits = [0 1];
            app.ThresholdSlider.ValueChangingFcn = createCallbackFcn(app, @ThresholdSliderValueChanging, true);
            app.ThresholdSlider.Value = 0.5;
            app.MinAreaSliderLabel = uilabel(app.UIFigure);
            app.MinAreaSliderLabel.Text = 'Min Area ';
            app.MinAreaSlider = uislider(app.UIFigure);
            app.MinAreaSlider.ValueChangingFcn = createCallbackFcn(app, @MinAreaSliderValueChanging, true);
            app.MinAreaSlider.Value = 50;
            app.InvertCheckBox = uicheckbox(app.UIFigure);
            app.InvertCheckBox.ValueChangedFcn = createCallbackFcn(app, @InvertCheckBoxValueChanged, true);
            app.InvertCheckBox.Text = 'Invert';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MenuDetectaVortices

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end