classdef MenuHyperuniformity < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HyperUniformityUIFigure  matlab.ui.Figure
        MaxREditField            matlab.ui.control.NumericEditField
        MaxREditFieldLabel       matlab.ui.control.Label
        PointsEditField          matlab.ui.control.NumericEditField
        PointsEditFieldLabel     matlab.ui.control.Label
        AllButton                matlab.ui.control.Button
        KSpaceButton             matlab.ui.control.Button
        RealSpaceButton          matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RealSpaceButton
        function RealSpaceButtonPushed(app, event)
            fig = findobj('Name', 'Image');
            Short = fig.UserData;
            maxR = app.MaxREditField.Value;
            nPuntosRadio = app.PointsEditField.Value;
            Varia = HyperuniformityReal(Short.centrosc,Short.Map, nPuntosRadio, maxR, fig);
        end

        % Button pushed function: KSpaceButton
        function KSpaceButtonPushed(app, event)
            fig = findobj('Name', 'Image');
            Short = fig.UserData;
            disp('oli')
          reciprocalHyperuniform(fig, Short.centrosc, Short.RealSize)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HyperUniformityUIFigure = uifigure('Visible', 'off');
            app.HyperUniformityUIFigure.Name = 'HyperUniformity';
            app.RealSpaceButton = uibutton(app.HyperUniformityUIFigure, 'push');
            app.RealSpaceButton.ButtonPushedFcn = createCallbackFcn(app, @RealSpaceButtonPushed, true);
            app.RealSpaceButton.Text = 'Real Space';
            app.KSpaceButton = uibutton(app.HyperUniformityUIFigure, 'push');
            app.KSpaceButton.ButtonPushedFcn = createCallbackFcn(app, @KSpaceButtonPushed, true);
            app.KSpaceButton.Text = 'K-Space';
            app.AllButton = uibutton(app.HyperUniformityUIFigure, 'push');
            app.AllButton.Text = 'All';
            app.PointsEditFieldLabel = uilabel(app.HyperUniformityUIFigure);
            app.PointsEditFieldLabel.Text = 'Points';
            app.PointsEditField = uieditfield(app.HyperUniformityUIFigure, 'numeric');
            app.PointsEditField.Value = 100;
            app.MaxREditFieldLabel = uilabel(app.HyperUniformityUIFigure);
            app.MaxREditFieldLabel.Text = 'Max R';
            app.MaxREditField = uieditfield(app.HyperUniformityUIFigure, 'numeric');
            app.MaxREditField.Limits = [0 1];
            app.MaxREditField.Value = 0.25;
            % Show the figure after all components are created
            app.HyperUniformityUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MenuHyperuniformity

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HyperUniformityUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HyperUniformityUIFigure)
        end
    end
end