classdef MenuHyperuniformityForOldVersions < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        RealSpaceButton       matlab.ui.control.Button
        KSpaceButton          matlab.ui.control.Button
        AllButton             matlab.ui.control.Button
        PointsEditFieldLabel  matlab.ui.control.Label
        PointsEditField       matlab.ui.control.NumericEditField
        MaxREditFieldLabel    matlab.ui.control.Label
        MaxREditField         matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Button pushed function: RealSpaceButton
        function RealSpaceButtonPushed(app, event)
            fig = findobj('Number', 1);
            Short = fig.UserData;
            maxR = app.MaxREditField.Value
            nPuntosRadio = app.PointsEditField.Value
            Varia = HyperuniformityReal(Short.centrosc,Short.Map, nPuntosRadio, maxR, fig);
        end

        % Button pushed function: KSpaceButton
        function KSpaceButtonPushed(app, event)
            fig = findobj('Number', 1);
            Short = fig.UserData;
            disp('oli')
          reciprocalHyperuniform(fig, Short.centrosc, Short.RealSize)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.RealSpaceButton = uibutton(app.UIFigure, 'push');
            app.RealSpaceButton.ButtonPushedFcn = createCallbackFcn(app, @RealSpaceButtonPushed, true);
            app.RealSpaceButton.Text = 'Real Space';
            app.KSpaceButton = uibutton(app.UIFigure, 'push');
            app.KSpaceButton.ButtonPushedFcn = createCallbackFcn(app, @KSpaceButtonPushed, true);
            app.KSpaceButton.Text = 'K-Space';
            app.AllButton = uibutton(app.UIFigure, 'push');
            app.AllButton.Text = 'All';
            app.PointsEditFieldLabel = uilabel(app.UIFigure);
            app.PointsEditFieldLabel.Text = 'Points';
            app.PointsEditField = uieditfield(app.UIFigure, 'numeric');
            app.PointsEditField.Value = 100;
            app.MaxREditFieldLabel = uilabel(app.UIFigure);
            app.MaxREditFieldLabel.Text = 'Max R';
            app.MaxREditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxREditField.Limits = [0 1];
            app.MaxREditField.Value = 0.25;
        end
    end

    methods (Access = public)

        % Construct app
        function app = MenuHyperuniformityForOldVersions

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end