classdef MenuV1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        BorderthresholdLabel   matlab.ui.control.Label
        FollowButton           matlab.ui.control.Button
        OldDataButton          matlab.ui.control.Button
        HyperuniformityButton  matlab.ui.control.Button
        CorrelationButton      matlab.ui.control.Button
        SaveButton             matlab.ui.control.Button
        ImageButton            matlab.ui.control.Button
        PlotButton             matlab.ui.control.Button
        DegreeSliderCalc       matlab.ui.control.Slider
        CalculationButton      matlab.ui.control.Button
        TriangulationButton    matlab.ui.control.Button
        CentroidsButton        matlab.ui.control.Button
    end

    
    properties (Access = public)
        Property % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            genpath('Functions')
            addpath(genpath('Functions'))    
        end

        % Button pushed function: CentroidsButton
        function CentroidsButtonPushed(app, event)
                %Este es el segundo boton que apretaras, basicmaente entra
                %en el modo centroides y busca la figura
            
                fig = findobj('Number', 1);
                fig.Children.Title.String = 'Centroids mode';
                fig.UserData.Mode = 'c';
                Short = fig.UserData;

                %Si no tiene un struct ya hecho al que acceder te abre
                %MenuDetectaVortices para sacarte la triangulacion. Si ya
                %tiene un struct con su triangulacion te la pinta encima
                %del mapa
                if isempty(fig.UserData.centrosc)
                   
                    MenuDetectaVortices
                else
                   
                    paint(Short.Map,Short.centrosc, Short.trian) 
                    display(Short.centrosc)

                end
        end

        % Button pushed function: TriangulationButton
        function TriangulationButtonPushed(app, event)
                fig = findobj('Number', 1);
                fig.Children.Title.String = 'Triangulation mode';
                fig.UserData.Mode = 't';
        end

        % Button pushed function: CalculationButton
        function CalculationButtonPushed(app, event)
                fig = findobj('Number', 1);
                fig.Children.Title.String = 'Neighbours mode';
                fig.UserData.Mode = 'n';
                fig.UserData.IndiceBorder = app.DegreeSliderCalc.Value;
                Short = fig.UserData;
                
                
                allow = allowCalculation(Short.centrosc, Short.allowed, Short.forbidden,...
                                         app.DegreeSliderCalc.Value, Short.Map);
                neighbours = numberOfNeighbours(allow, Short.trian);
                fig.UserData.neighbour = neighbours;
                fig.UserData.allow = allow;
                paint(Short.Map,Short.centrosc, Short.trian,allow, neighbours)
                
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            fig = findobj('Number', 1);
           
            realSize = fig.UserData.RealSize;
            Map = fig.UserData.Map;

             plotNumberOfNeighbours(fig.UserData.neighbour)
            
             
           centrosC = fig.UserData.centrosc;
           centrosC = realSize.*centrosC./size(Map(:,:,1));
            [distance, pairs] = distanceNeighbours(fig.UserData.allow, centrosC, fig.UserData.trian);
            %distance = realSize(1).*distance/length(Map);
            plotDistanceOfNeighbours(distance)
            
            fig.UserData.distance = distance;
            fig.UserData.pairs = pairs;
             
            
             Media = stabilityMapV2( fig.UserData.centrosc, fig.UserData.trian, fig.UserData.allow);
             paintWithVoronoi(Media, fig.UserData.Map, fig.UserData.centrosc, fig.UserData.trian, fig.UserData.allow)
        end

        % Button pushed function: ImageButton
        function ImageButtonPushed(app, event)
            %Este es el primer boton que pulsas, introduce las imagenes que
            %quieres analizar.
            
            [file path] = uigetfile('*.*');
            [~,name,ext] = fileparts(file);
%             close all
            fig = figure(1);
            fig.Name = 'Image';
            fig.WindowButtonDownFcn = {@WindowPress};
            fig.WindowKeyPressFcn = {@WindowKey};
            
            %%Inicializacion de las variables mas importantes
            fig.UserData.allowed = [];
            fig.UserData.forbidden = [];
            fig.UserData.centrosc = [];
            fig.UserData.trian = [];
   
            %Aqui se guarda la informacion que tiene de las imagenes.
            %Tambien te pregunta por el tamaño real de la imagen 
            %(si en la misma carpeta que la imagen tienes un struct con el 
            %nombre apropiado te lo pilla solito)
            Map = imread([path name ext]);
            fig.UserData.Path = path;
            fig.UserData.Name = name;
            fig.UserData.ext = ext;
            imshow([path name ext]);
            if exist([path 'Struct' name '.mat' ])
                Info = load([path 'Struct' name '.mat' ]);
                fig.UserData = Info.a;
            else
                fig.UserData.Map = Map;
                
                answer = inputdlg({'Size in X', 'Size in Y'}, 'Enter the real size in nm');
                RealX = str2double(cell2mat(answer(1)));
                RealY = str2double(cell2mat(answer(2)));
                fig.UserData.RealSize = [RealX RealY];
                
            end
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            fig = findobj('Number', 1);
            a = fig.UserData;
            folder = uigetdir(fig.UserData.Path);
            save([folder,filesep,'Struct',fig.UserData.Name,'.mat'], 'a') 
        end

        % Value changing function: DegreeSliderCalc
        function DegreeSliderCalcValueChanging(app, event)
            changingValue = event.Value;
                           fig = findobj('Number', 1);
                fig.Children.Title.String = 'Neighbours mode';
                fig.UserData.Mode = 'n';
                fig.UserData.IndiceBorder = changingValue;
                Short = fig.UserData;
                
                
                allow = allowCalculation(Short.centrosc, Short.allowed, Short.forbidden,...
                                         app.DegreeSliderCalc.Value, Short.Map);
                neighbours = numberOfNeighbours(allow, Short.trian);
                fig.UserData.neighbour = neighbours;
             
                paint(Short.Map,Short.centrosc, Short.trian,allow, neighbours)
                
        end

        % Button pushed function: HyperuniformityButton
        function HyperuniformityButtonPushed(app, event)
          if isempty(findall(0, 'Name', 'HyperUniformity'))
                MenuHyperuniformity;
          else
              delete(findall(0, 'Name', 'HyperUniformity'))
              MenuHyperuniformity
          end
            
        end

        % Button pushed function: OldDataButton
        function OldDataButtonPushed(app, event)
            
            %Abro Imagen
            [file, path] = uigetfile('*.*');
            [~,name,ext] = fileparts(file)
            fig = figure(1);
            fig.Name = 'Image';
            fig.WindowButtonDownFcn = {@WindowPress};
            fig.WindowKeyPressFcn = {@WindowKey};
            
            %Abro Centroids
            [fileCenter, pathCenter] = uigetfile('*.*', 'Choose Centers');
            [~,name,ext] = fileparts(fileCenter);
            [~,nameCenter,extCenter] = fileparts(fileCenter);
            
            %Abro triangulation
           % [fileTrian, pathTrian] = uigetfile('*.*', 'Choose Triangulation', path);
            %[~,nameTrian,extTrian] = fileparts(fileTrian);
            
                       %%Inicializacion
            fig.UserData.allowed = [];
            fig.UserData.forbidden = [];
            
            Map = imread([path name ext]);
           
            Centroids = openFile(pathCenter,nameCenter, extCenter);  
           % [max(Centroids(:,1)), max(Centroids(:,2))]
           % Map = imresize(Map, [max(Centroids(:,2)), max(Centroids(:,1))]);
           %Map = zeros(max(Centroids(:,2)), max(Centroids(:,1)));
           % Centroids = [Centroids(:,1), flipud(Centroids(:,2))];
            if max(max(Centroids)) <1.1
               
                [sz1,sz2,~] = size(Map);
                 Centroids = Centroids.*[sz1,sz2];
            end
            fig.UserData.centrosc = Centroids;
            
           % trian = openFile(pathTrian,nameTrian, extTrian);   
            
            fig.UserData.trian = 0;


            fig.UserData.Path = path;
            fig.UserData.Name = name;
            fig.UserData.ext = ext;
            %imshow([path name ext]);
            imshow(Map)
            
            fig.UserData.Map = Map;
            answer = inputdlg({'Size in X', 'Size in Y'}, 'Enter the real size in nm');
            RealX = str2double(cell2mat(answer(1)));
            RealY = str2double(cell2mat(answer(2)));
            fig.UserData.RealSize = [RealX RealY];
            
            
        end

        % Button pushed function: FollowButton
        function FollowButtonPushed(app, event)
            fig = openFigure('Image');
            fig.Visible = 'off';
             [path, name, ext] = openFollow();


            fig.WindowButtonDownFcn = {@WindowPress};
            fig.WindowKeyPressFcn = {@WindowKey};
            
             %%Inicializacion
            fig.UserData.allowed = [];
            fig.UserData.forbidden = [];
            fig.UserData.centrosc = [];
            fig.UserData.trian = [];


            if exist([path 'Struct' name{1} '.mat' ])
                Info = load([path 'Struct' name{1} '.mat' ])
                fig.UserData = Info.a;
            else
                
                answer = inputdlg({'Size in X', 'Size in Y'}, 'Enter the real size in nm');
                RealX = str2double(cell2mat(answer(1)));
                RealY = str2double(cell2mat(answer(2)));
                fig.UserData.RealSize = [RealX RealY];
                
            end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.CentroidsButton = uibutton(app.UIFigure, 'push');
            app.CentroidsButton.ButtonPushedFcn = createCallbackFcn(app, @CentroidsButtonPushed, true);
            app.CentroidsButton.Text = 'Centroids';
            app.TriangulationButton = uibutton(app.UIFigure, 'push');
            app.TriangulationButton.ButtonPushedFcn = createCallbackFcn(app, @TriangulationButtonPushed, true);
            app.TriangulationButton.Text = 'Triangulation';
            app.CalculationButton = uibutton(app.UIFigure, 'push');
            app.CalculationButton.ButtonPushedFcn = createCallbackFcn(app, @CalculationButtonPushed, true);
            app.CalculationButton.Text = 'Calculation';
            app.DegreeSliderCalc = uislider(app.UIFigure);
            app.DegreeSliderCalc.Limits = [0 1];
            app.DegreeSliderCalc.ValueChangingFcn = createCallbackFcn(app, @DegreeSliderCalcValueChanging, true);
            app.DegreeSliderCalc.Value = 0.5;
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ImageButton = uibutton(app.UIFigure, 'push');
            app.ImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImageButtonPushed, true);
            app.ImageButton.Text = 'Image';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CorrelationButton = uibutton(app.UIFigure, 'push');
            app.CorrelationButton.Text = 'Correlation!!!';
            app.HyperuniformityButton = uibutton(app.UIFigure, 'push');
            app.HyperuniformityButton.ButtonPushedFcn = createCallbackFcn(app, @HyperuniformityButtonPushed, true);
            app.HyperuniformityButton.Text = 'Hyperuniformity';
            app.OldDataButton = uibutton(app.UIFigure, 'push');
            app.OldDataButton.ButtonPushedFcn = createCallbackFcn(app, @OldDataButtonPushed, true);
            app.OldDataButton.Text = 'Old Data';
            app.FollowButton = uibutton(app.UIFigure, 'push');
            app.FollowButton.ButtonPushedFcn = createCallbackFcn(app, @FollowButtonPushed, true);
            app.FollowButton.Text = 'Follow';
            app.BorderthresholdLabel = uilabel(app.UIFigure);
            app.BorderthresholdLabel.Text = 'Border threshold';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MenuV1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end