classdef MenuV1ForOldVersions < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        CentroidsButton        matlab.ui.control.Button
        TriangulationButton    matlab.ui.control.Button
        CalculationButton      matlab.ui.control.Button
        DegreeSliderCalc       matlab.ui.control.Slider
        PlotButton             matlab.ui.control.Button
        ImageButton            matlab.ui.control.Button
        SaveButton             matlab.ui.control.Button
        CorrelationButton      matlab.ui.control.Button
        HyperuniformityButton  matlab.ui.control.Button
        OldDataButton          matlab.ui.control.Button
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            genpath('Functions')
            addpath(genpath('Functions'))    
        end

        % Button pushed function: CentroidsButton
        function CentroidsButtonPushed(app, event)
                fig = findobj('Number', 1)
                fig.Children.Title.String = 'Centroids mode';
                fig.UserData.Mode = 'c';
                Short = fig.UserData;
                if isempty(fig.UserData.centrosc)
                   
                    MenuDetectaVortices
                else
                   
                    paint(Short.Map,Short.centrosc, Short.trian) 

                end
        end

        % Button pushed function: TriangulationButton
        function TriangulationButtonPushed(app, event)
                fig = findobj('Number', 1)
                fig.Children.Title.String = 'Triangulation mode';
                fig.UserData.Mode = 't';
        end

        % Button pushed function: CalculationButton
        function CalculationButtonPushed(app, event)
                fig = findobj('Number', 1)
                fig.Children.Title.String = 'Neighbours mode';
                fig.UserData.Mode = 'n';
                fig.UserData.IndiceBorder = app.DegreeSliderCalc.Value;
                Short = fig.UserData;
                
                calculateAllDistances(Short.centrosc)
                allow = allowCalculation(Short.centrosc, Short.allowed, Short.forbidden,...
                                         app.DegreeSliderCalc.Value, Short.Map);
                neighbours = numberOfNeighbours(allow, Short.trian);
                fig.UserData.neighbour = neighbours;
                fig.UserData.allow = allow;
                paint(Short.Map,Short.centrosc, Short.trian,allow, neighbours)
                
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            fig = findobj('Number', 1)
            figure
            histogram(fig.UserData.neighbour) 
            figure
             Media = stabilityMap( fig.UserData.centrosc, fig.UserData.trian);
             paintWithVoronoi(Media, fig.UserData.Map, fig.UserData.centrosc, fig.UserData.trian)
        end

        % Button pushed function: ImageButton
        function ImageButtonPushed(app, event)
            [file path] = uigetfile('*.*');
            [~,name,ext] = fileparts(file);
            fig = figure(1);
            fig.WindowButtonDownFcn = {@WindowPress};
            fig.WindowKeyPressFcn = {@WindowKey};
            
            %%Inicializacion
            fig.UserData.allowed = [];
            fig.UserData.forbidden = [];
            fig.UserData.centrosc = [];
            fig.UserData.trian = [];
   
            Map = imread([path name ext]);
            fig.UserData.Path = path;
            fig.UserData.Name = name;
            fig.UserData.ext = ext;
            imshow([path name ext]);
            if exist([path 'Struct' name '.mat' ])
                Info = load([path 'Struct' name '.mat' ])
                fig.UserData = Info.a;
            else
                fig.UserData.Map = Map;
                answer = inputdlg({'Size in X', 'Size in Y'}, 'Enter the real size in nm');
                RealX = str2double(cell2mat(answer(1)));
                RealY = str2double(cell2mat(answer(2)));
                fig.UserData.RealSize = [RealX RealY];
                
            end
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            fig = findobj('Number', 1);
            a = fig.UserData;
            folder = uigetdir(fig.UserData.Path);
            save([folder,'\Struct',fig.UserData.Name,'.mat'], 'a') 
        end

        % Value changing function: DegreeSliderCalc
        function DegreeSliderCalcValueChanging(app, event)
            changingValue = event.Value;
                           fig = findobj('Number', 1)
                fig.Children.Title.String = 'Neighbours mode';
                fig.UserData.Mode = 'n';
                fig.UserData.IndiceBorder = changingValue;
                Short = fig.UserData;
                
                
                allow = allowCalculation(Short.centrosc, Short.allowed, Short.forbidden,...
                                         app.DegreeSliderCalc.Value, Short.Map);
                neighbours = numberOfNeighbours(allow, Short.trian);
                fig.UserData.neighbour = neighbours;
             
                paint(Short.Map,Short.centrosc, Short.trian,allow, neighbours)
                
        end

        % Button pushed function: HyperuniformityButton
        function HyperuniformityButtonPushed(app, event)
            
          MenuHyperuniformity;
            
        end

        % Button pushed function: OldDataButton
        function OldDataButtonPushed(app, event)
            
            %Abro Imagen
            [file, path] = uigetfile('*.*');
            [~,name,ext] = fileparts(file);
            fig = figure(1);
            fig.WindowButtonDownFcn = {@WindowPress};
            fig.WindowKeyPressFcn = {@WindowKey};
            
            %Abro Centroids
            [fileCenter, pathCenter] = uigetfile('*.*', 'Choose Centers', path);
            [~,nameCenter,extCenter] = fileparts(fileCenter);
            
            %Abro triangulation
           % [fileTrian, pathTrian] = uigetfile('*.*', 'Choose Triangulation', path);
            %[~,nameTrian,extTrian] = fileparts(fileTrian);
            
                       %%Inicializacion
            fig.UserData.allowed = [];
            fig.UserData.forbidden = [];
            
            Map = imread([path name ext]);
            Centroids = openFile(pathCenter,nameCenter, extCenter);   
            
            if max(max(Centroids)) <1.1
               
                [sz1,sz2,~] = size(Map);
                 Centroids = Centroids.*[sz1,sz2];
            end
            fig.UserData.centrosc = Centroids;
            
           % trian = openFile(pathTrian,nameTrian, extTrian);   
            
            fig.UserData.trian = 0;


            fig.UserData.Path = path;
            fig.UserData.Name = name;
            fig.UserData.ext = ext;
            imshow([path name ext]);
            
            
            fig.UserData.Map = Map;
            answer = inputdlg({'Size in X', 'Size in Y'}, 'Enter the real size in nm');
            RealX = str2double(cell2mat(answer(1)));
            RealY = str2double(cell2mat(answer(2)));
            fig.UserData.RealSize = [RealX RealY];
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.CentroidsButton = uibutton(app.UIFigure, 'push');
            app.CentroidsButton.ButtonPushedFcn = createCallbackFcn(app, @CentroidsButtonPushed, true);
            app.CentroidsButton.Text = 'Centroids';
            app.TriangulationButton = uibutton(app.UIFigure, 'push');
            app.TriangulationButton.ButtonPushedFcn = createCallbackFcn(app, @TriangulationButtonPushed, true);
            app.TriangulationButton.Text = 'Triangulation';
            app.CalculationButton = uibutton(app.UIFigure, 'push');
            app.CalculationButton.ButtonPushedFcn = createCallbackFcn(app, @CalculationButtonPushed, true);
            app.CalculationButton.Text = 'Calculation';
            app.DegreeSliderCalc = uislider(app.UIFigure);
            app.DegreeSliderCalc.Limits = [0 1];
            app.DegreeSliderCalc.ValueChangingFcn = createCallbackFcn(app, @DegreeSliderCalcValueChanging, true);
            app.DegreeSliderCalc.Value = 0.5;
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ImageButton = uibutton(app.UIFigure, 'push');
            app.ImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImageButtonPushed, true);
            app.ImageButton.Text = 'Image';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CorrelationButton = uibutton(app.UIFigure, 'push');
            app.CorrelationButton.Text = 'Correlation';
            app.HyperuniformityButton = uibutton(app.UIFigure, 'push');
            app.HyperuniformityButton.ButtonPushedFcn = createCallbackFcn(app, @HyperuniformityButtonPushed, true);
            app.HyperuniformityButton.Text = 'Hyperuniformity';
            app.OldDataButton = uibutton(app.UIFigure, 'push');
            app.OldDataButton.ButtonPushedFcn = createCallbackFcn(app, @OldDataButtonPushed, true);
            app.OldDataButton.Text = 'Old Data';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MenuV1ForOldVersions

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end